classdef esn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EchoStateNetworkUIFigure    matlab.ui.Figure
        Plot                        matlab.ui.control.UIAxes
        GoButton                    matlab.ui.control.Button
        SamplesSpinnerLabel         matlab.ui.control.Label
        SamplesSpinner              matlab.ui.control.Spinner
        NetworkSizeSpinnerLabel     matlab.ui.control.Label
        NetworkSizeSpinner          matlab.ui.control.Spinner
        TrainSpinnerLabel           matlab.ui.control.Label
        TrainSpinner                matlab.ui.control.Spinner
        TestSpinnerLabel            matlab.ui.control.Label
        TestSpinner                 matlab.ui.control.Spinner
        Label                       matlab.ui.control.Label
        AlphaSlider                 matlab.ui.control.Slider
        DensitySliderLabel          matlab.ui.control.Label
        DensitySlider               matlab.ui.control.Slider
        NoiseSliderLabel            matlab.ui.control.Label
        NoiseSlider                 matlab.ui.control.Slider
        AlphaLabel                  matlab.ui.control.Label
        DensityLabel                matlab.ui.control.Label
        NoiseLabel                  matlab.ui.control.Label
        MseLabel                    matlab.ui.control.Label
        ResetAfterTrainingCheckBox  matlab.ui.control.CheckBox
    end


    properties (Access = private)
        Alpha % Description
        Density % Description
        Noise % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function esnInit(app)
            app.Alpha = 1/(1+exp(-10*app.AlphaSlider.Value));
            app.AlphaLabel.Text = sprintf('%.5g', app.Alpha);
            app.Density = app.DensitySlider.Value^2;
            app.DensityLabel.Text = sprintf('%.5g', app.Density);
            app.Noise = 0.01*(app.NoiseSlider.Value/0.01)^2;
            app.NoiseLabel.Text = sprintf('%.5g', app.Noise);
            
            app.Plot.XLim = [1, app.SamplesSpinner.Value];
            
            app.Plot.YLim = [-1, 1];
        end

        % Value changing function: AlphaSlider
        function alphaSliderChange(app, event)
            app.Alpha = 1/(1+exp(-10*event.Value));
            app.AlphaLabel.Text = sprintf('%.7f', app.Alpha);
        end

        % Value changing function: DensitySlider
        function densitySliderChange(app, event)
            app.Density = event.Value^2;
            app.DensityLabel.Text = sprintf('%.7f', app.Density);
        end

        % Callback function: AlphaSlider, DensitySlider, GoButton, 
        % NetworkSizeSpinner, NoiseSlider, ResetAfterTrainingCheckBox, 
        % SamplesSpinner, TestSpinner, TrainSpinner
        function esnGo(app, event)
            [ data, mse ] = esn_run(...
                app.SamplesSpinner.Value, ... 
                app.NetworkSizeSpinner.Value, ...
                app.TrainSpinner.Value, ...
                app.TestSpinner.Value, ...
                app.Alpha, ...
                app.Density, ...
                app.Noise, ...
                app.ResetAfterTrainingCheckBox.Value);
            plot(app.Plot,data(:,1),data(:,2),data(:,1),data(:,3));
            app.MseLabel.Text = sprintf('MSE: %.5g', mse);
            legend(app.Plot, 'Original', 'Generated');
        end

        % Value changing function: NoiseSlider
        function noiseSliderChange(app, event)
            app.Noise = 0.01*(event.Value/0.01)^2;
            app.NoiseLabel.Text = sprintf('%.7f', app.Noise);
        end

        % Value changing function: SamplesSpinner
        function samplesSpinnerChange(app, event)
            app.Plot.XLim = [1, event.Value * app.TestSpinner.Value];
        end

        % Value changing function: TestSpinner
        function testSpinnerChange(app, event)
            app.Plot.XLim = [1, app.SamplesSpinner.Value * event.Value];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EchoStateNetworkUIFigure = uifigure;
            app.EchoStateNetworkUIFigure.Name = 'Echo State Network';
            app.Plot = uiaxes(app.EchoStateNetworkUIFigure);
            app.GoButton = uibutton(app.EchoStateNetworkUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @esnGo, true);
            app.GoButton.Text = 'Go';
            app.SamplesSpinnerLabel = uilabel(app.EchoStateNetworkUIFigure);
            app.SamplesSpinnerLabel.Text = 'Samples';
            app.SamplesSpinner = uispinner(app.EchoStateNetworkUIFigure);
            app.SamplesSpinner.ValueChangingFcn = createCallbackFcn(app, @samplesSpinnerChange, true);
            app.SamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @esnGo, true);
            app.SamplesSpinner.Limits = [10 10000];
            app.SamplesSpinner.Value = 60;
            app.NetworkSizeSpinnerLabel = uilabel(app.EchoStateNetworkUIFigure);
            app.NetworkSizeSpinnerLabel.Text = 'Network Size';
            app.NetworkSizeSpinner = uispinner(app.EchoStateNetworkUIFigure);
            app.NetworkSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @esnGo, true);
            app.NetworkSizeSpinner.Limits = [1 Inf];
            app.NetworkSizeSpinner.Value = 200;
            app.TrainSpinnerLabel = uilabel(app.EchoStateNetworkUIFigure);
            app.TrainSpinnerLabel.Text = 'Train';
            app.TrainSpinner = uispinner(app.EchoStateNetworkUIFigure);
            app.TrainSpinner.ValueChangedFcn = createCallbackFcn(app, @esnGo, true);
            app.TrainSpinner.Limits = [1 Inf];
            app.TrainSpinner.Value = 10;
            app.TestSpinnerLabel = uilabel(app.EchoStateNetworkUIFigure);
            app.TestSpinnerLabel.Text = 'Test';
            app.TestSpinner = uispinner(app.EchoStateNetworkUIFigure);
            app.TestSpinner.ValueChangingFcn = createCallbackFcn(app, @testSpinnerChange, true);
            app.TestSpinner.ValueChangedFcn = createCallbackFcn(app, @esnGo, true);
            app.TestSpinner.Limits = [1 Inf];
            app.TestSpinner.Value = 1;
            app.Label = uilabel(app.EchoStateNetworkUIFigure);
            app.Label.Text = 'α';
            app.AlphaSlider = uislider(app.EchoStateNetworkUIFigure);
            app.AlphaSlider.Limits = [0 1];
            app.AlphaSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.AlphaSlider.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '1'};
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @esnGo, true);
            app.AlphaSlider.ValueChangingFcn = createCallbackFcn(app, @alphaSliderChange, true);
            app.AlphaSlider.Value = 0.9;
            app.DensitySliderLabel = uilabel(app.EchoStateNetworkUIFigure);
            app.DensitySliderLabel.Text = 'Density';
            app.DensitySlider = uislider(app.EchoStateNetworkUIFigure);
            app.DensitySlider.Limits = [0 1];
            app.DensitySlider.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '1'};
            app.DensitySlider.ValueChangedFcn = createCallbackFcn(app, @esnGo, true);
            app.DensitySlider.ValueChangingFcn = createCallbackFcn(app, @densitySliderChange, true);
            app.DensitySlider.Value = 0.2;
            app.NoiseSliderLabel = uilabel(app.EchoStateNetworkUIFigure);
            app.NoiseSliderLabel.Text = 'Noise';
            app.NoiseSlider = uislider(app.EchoStateNetworkUIFigure);
            app.NoiseSlider.Limits = [0 0.01];
            app.NoiseSlider.MajorTickLabels = {'0', '', '', '', '', '0.01'};
            app.NoiseSlider.ValueChangedFcn = createCallbackFcn(app, @esnGo, true);
            app.NoiseSlider.ValueChangingFcn = createCallbackFcn(app, @noiseSliderChange, true);
            app.NoiseSlider.Value = 0.001;
            app.AlphaLabel = uilabel(app.EchoStateNetworkUIFigure);
            app.DensityLabel = uilabel(app.EchoStateNetworkUIFigure);
            app.DensityLabel.Text = 'Label2';
            app.NoiseLabel = uilabel(app.EchoStateNetworkUIFigure);
            app.NoiseLabel.Text = 'Label3';
            app.MseLabel = uilabel(app.EchoStateNetworkUIFigure);
            app.MseLabel.Text = 'MSE:';
            app.ResetAfterTrainingCheckBox = uicheckbox(app.EchoStateNetworkUIFigure);
            app.ResetAfterTrainingCheckBox.ValueChangedFcn = createCallbackFcn(app, @esnGo, true);
            app.ResetAfterTrainingCheckBox.Text = 'Reset After Training';
        end
    end

    methods (Access = public)

        % Construct app
        function app = esn

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EchoStateNetworkUIFigure)

            % Execute the startup function
            runStartupFcn(app, @esnInit)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EchoStateNetworkUIFigure)
        end
    end
end